classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        image_processingButton  matlab.ui.control.Button
        computeButton           matlab.ui.control.Button
        loadButton              matlab.ui.control.Button
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filename ="C:\Users\user\Desktop\大学院\講義\機械学習基礎\logo_csse.png";
            img = imread(filename);
            imshow(img, 'Parent', app.UIAxes);

        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)

            
            filename_2 = "C:\Users\user\Desktop\大学院\講義\機械学習基礎\logo_csse.png";
            im=imread(filename_2);
            imgray = rgb2gray(im);
            BW = imbinarize(imgray);
            % 二値化した画像を表示
            imshow(BW*255, 'Parent', app.UIAxes2);
            app.BinarizedImage = binaryImg;
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Callback function
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Callback function
        function UIFigureCloseRequest2(app, event)
            delete(app)
            
        end

        % Button pushed function: image_processingButton
        function image_processingButtonPushed(app, event)
            filename_3=imread("C:\Users\user\Desktop\大学院\講義\機械学習基礎\logo_csse.png")
            im=imread(filename_3);
            subplot(4,1,1)
            imshow(im,'Parent', app.UIAxes3)
            subplot(4,1,2)
            imshow(im(:,:,1),'Parent', app.UIAxes3)
            subplot(4,1,3)
            imshow(im(:,:,2),'Parent', app.UIAxes3)
            subplot(4,1,4)
            imshow(im(:,:,3),'Parent', app.UIAxes3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Panel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.loadButton = uibutton(app.Panel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.Panel, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.image_processingButton = uibutton(app.Panel, 'push');
            app.image_processingButton.ButtonPushedFcn = createCallbackFcn(app, @image_processingButtonPushed, true);
            app.image_processingButton.Text = 'image_processing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end